classdef F_SS_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaturationcountsLabel          matlab.ui.control.Label
        FaceCoordinatesLabel           matlab.ui.control.Label
        ScaleFactorsLabel              matlab.ui.control.Label
        UnitVectorLabel                matlab.ui.control.Label
        FineSunSensorSettingsLabel     matlab.ui.control.Label
        SensitivityLSBradEditFieldLabel  matlab.ui.control.Label
        SensitivityLSBradEditField     matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        UpperLimitEditField            matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel       matlab.ui.control.Label
        LowerLimitEditField            matlab.ui.control.NumericEditField
        UpdateRatesEditFieldLabel      matlab.ui.control.Label
        UpdateRatesEditField           matlab.ui.control.NumericEditField
        XdirectionEditFieldLabel       matlab.ui.control.Label
        XdirectionEditField            matlab.ui.control.NumericEditField
        YdirectionEditFieldLabel       matlab.ui.control.Label
        YdirectionEditField            matlab.ui.control.NumericEditField
        ZdirectionEditFieldLabel       matlab.ui.control.Label
        ZdirectionEditField            matlab.ui.control.NumericEditField
        g_1EditFieldLabel              matlab.ui.control.Label
        g_1EditField                   matlab.ui.control.NumericEditField
        g_2EditFieldLabel              matlab.ui.control.Label
        g_2EditField                   matlab.ui.control.NumericEditField
        g_3EditFieldLabel              matlab.ui.control.Label
        g_3EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4Label               matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6Label               matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        NoiseStdDeviationradEditFieldLabel  matlab.ui.control.Label
        NoiseStdDeviationradEditField  matlab.ui.control.NumericEditField
        FieldofViewEditFieldLabel      matlab.ui.control.Label
        FieldofViewEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        face_x;
        face_y;
        face_z;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            sensitivity_F_SS = app.SensitivityLSBradEditField.Value;
            assignin('base','Sensitivity_F_SS',sensitivity_F_SS)
            
            upper_F_SS = app.UpperLimitEditField.Value;
            assignin('base','Upper_F_SS',upper_F_SS)
            
            lower_F_SS = app.LowerLimitEditField.Value;
            assignin('base','Lower_F_SS',lower_F_SS)
            
            noise_F_SS = app.NoiseStdDeviationradEditField.Value;
            assignin('base','Noise_F_SS',noise_F_SS)
            
            rate_F_SS = app.UpdateRatesEditField.Value;
            assignin('base','Rate_F_SS',rate_F_SS)
            
            fov_F_SS = app.FieldofViewEditField.Value;
            assignin('base','FoV_F_SS',fov_F_SS)
            
            app.face_x = app.XdirectionEditField.Value;
            app.face_y = app.YdirectionEditField.Value;
            app.face_z = app.ZdirectionEditField.Value;
            assignin('base','Face_F_SS',[app.face_x app.face_y app.face_z])
            
        end

        % Value changed function: SensitivityLSBradEditField
        function SensitivityLSBradEditFieldValueChanged(app, event)
            sensitivity_F_SS = app.SensitivityLSBradEditField.Value;
            assignin('base','Sensitivity_F_SS',sensitivity_F_SS)
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            upper_F_SS = app.UpperLimitEditField.Value;
            assignin('base','Upper_F_SS',upper_F_SS)
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            lower_F_SS = app.LowerLimitEditField.Value;
            assignin('base','Lower_F_SS',lower_F_SS)
        end

        % Value changed function: NoiseStdDeviationradEditField
        function NoiseStdDeviationradEditFieldValueChanged(app, event)
            noise_F_SS = app.NoiseStdDeviationradEditField.Value;
            assignin('base','Noise_F_SS',noise_F_SS)
        end

        % Value changed function: UpdateRatesEditField
        function UpdateRatesEditFieldValueChanged(app, event)
            rate_F_SS = app.UpdateRatesEditField.Value;
            assignin('base','Rate_F_SS',rate_F_SS)
        end

        % Value changed function: XdirectionEditField
        function XdirectionEditFieldValueChanged(app, event)
            app.face_x = app.XdirectionEditField.Value;
            assignin('base','Face_F_SS',[app.face_x app.face_y app.face_z])
        end

        % Value changed function: YdirectionEditField
        function YdirectionEditFieldValueChanged(app, event)
            app.face_y = app.YdirectionEditField.Value;
            assignin('base','Face_F_SS',[app.face_x app.face_y app.face_z])
        end

        % Value changed function: ZdirectionEditField
        function ZdirectionEditFieldValueChanged(app, event)
            app.face_z = app.ZdirectionEditField.Value;
            assignin('base','Face_F_SS',[app.face_x app.face_y app.face_z])
        end

        % Value changed function: FieldofViewEditField
        function FieldofViewEditFieldValueChanged(app, event)
            fov_F_SS = app.FieldofViewEditField.Value;
            assignin('base','FoV_F_SS',fov_F_SS)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaturationcountsLabel = uilabel(app.UIFigure);
            app.SaturationcountsLabel.Text = 'Saturation [counts]:';
            app.FaceCoordinatesLabel = uilabel(app.UIFigure);
            app.FaceCoordinatesLabel.Text = 'Face Coordinates*:';
            app.ScaleFactorsLabel = uilabel(app.UIFigure);
            app.ScaleFactorsLabel.Text = 'Scale Factors*:';
            app.UnitVectorLabel = uilabel(app.UIFigure);
            app.UnitVectorLabel.Text = '*Unit Vector';
            app.FineSunSensorSettingsLabel = uilabel(app.UIFigure);
            app.FineSunSensorSettingsLabel.Text = 'Fine Sun Sensor Settings';
            app.SensitivityLSBradEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityLSBradEditFieldLabel.Text = 'Sensitivity [LSB/rad]';
            app.SensitivityLSBradEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensitivityLSBradEditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityLSBradEditFieldValueChanged, true);
            app.SensitivityLSBradEditField.Value = 71428571.4285714;
            app.UpperLimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Value = 32767;
            app.LowerLimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.LowerLimitEditField.Value = -32768;
            app.UpdateRatesEditFieldLabel = uilabel(app.UIFigure);
            app.UpdateRatesEditFieldLabel.Text = 'Update Rate [s]';
            app.UpdateRatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateRatesEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateRatesEditFieldValueChanged, true);
            app.UpdateRatesEditField.Value = 0.1;
            app.XdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.XdirectionEditFieldLabel.Text = 'X direction';
            app.XdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.XdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XdirectionEditFieldValueChanged, true);
            app.YdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.YdirectionEditFieldLabel.Text = 'Y direction';
            app.YdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @YdirectionEditFieldValueChanged, true);
            app.ZdirectionEditFieldLabel = uilabel(app.UIFigure);
            app.ZdirectionEditFieldLabel.Text = 'Z direction';
            app.ZdirectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @ZdirectionEditFieldValueChanged, true);
            app.ZdirectionEditField.Value = -1;
            app.g_1EditFieldLabel = uilabel(app.UIFigure);
            app.g_1EditFieldLabel.Text = 'g_1';
            app.g_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.g_1EditField.Value = 1;
            app.g_2EditFieldLabel = uilabel(app.UIFigure);
            app.g_2EditFieldLabel.Text = 'g_2';
            app.g_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.g_3EditFieldLabel = uilabel(app.UIFigure);
            app.g_3EditFieldLabel.Text = 'g_3';
            app.g_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'θ_1';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'θ_2';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 1;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'θ_3';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = 'φ_1';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = 'φ_1';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = 'φ_1';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 1;
            app.NoiseStdDeviationradEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseStdDeviationradEditFieldLabel.Text = 'Noise Std Deviation [rad]';
            app.NoiseStdDeviationradEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseStdDeviationradEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseStdDeviationradEditFieldValueChanged, true);
            app.NoiseStdDeviationradEditField.Value = 5e-09;
            app.FieldofViewEditFieldLabel = uilabel(app.UIFigure);
            app.FieldofViewEditFieldLabel.Text = 'Field of View';
            app.FieldofViewEditField = uieditfield(app.UIFigure, 'numeric');
            app.FieldofViewEditField.ValueChangedFcn = createCallbackFcn(app, @FieldofViewEditFieldValueChanged, true);
            app.FieldofViewEditField.Value = 3.14159265358979;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F_SS_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end