classdef menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainSystemTab                   matlab.ui.container.Tab
        RunSimulinkButton               matlab.ui.control.Button
        StopTimesecondsEditFieldLabel   matlab.ui.control.Label
        StopTimesecondsEditField        matlab.ui.control.NumericEditField
        SolverDropDownLabel             matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        ADCSSimulatorforCubeSatLabel    matlab.ui.control.Label
        StepSizesecondsEditFieldLabel   matlab.ui.control.Label
        StepSizesecondsEditField        matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.EditField
        OrbitandEnvironmentTab          matlab.ui.container.Tab
        PerturbationsButton             matlab.ui.control.Button
        SpacecraftParametersLabel       matlab.ui.control.Label
        StartEpochEditFieldLabel        matlab.ui.control.Label
        StartEpochEditField             matlab.ui.control.NumericEditField
        EpochFormatDropDownLabel        matlab.ui.control.Label
        EpochFormatDropDown             matlab.ui.control.DropDown
        MeanAnomalydegEditFieldLabel    matlab.ui.control.Label
        MeanAnomalydegEditField         matlab.ui.control.NumericEditField
        InclinationdegEditFieldLabel    matlab.ui.control.Label
        InclinationdegEditField         matlab.ui.control.NumericEditField
        ArgumentofPerigeedegEditFieldLabel  matlab.ui.control.Label
        ArgumentofPerigeedegEditField   matlab.ui.control.NumericEditField
        LongitudeofAscendingNodedegEditFieldLabel  matlab.ui.control.Label
        LongitudeofAscendingNodedegEditField  matlab.ui.control.NumericEditField
        SemiMajorAxiskmEditFieldLabel   matlab.ui.control.Label
        SemiMajorAxiskmEditField        matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel      matlab.ui.control.Label
        EccentricityEditField           matlab.ui.control.NumericEditField
        CoordinateSystemDropDownLabel   matlab.ui.control.Label
        CoordinateSystemDropDown        matlab.ui.control.DropDown
        SpacecraftTab                   matlab.ui.container.Tab
        radLabel                        matlab.ui.control.Label
        ReferenceFrameDropDown          matlab.ui.control.DropDown
        radLabel_2                      matlab.ui.control.Label
        radLabel_3                      matlab.ui.control.Label
        ReferenceFrameDropDownLabel     matlab.ui.control.Label
        cm3Label                        matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        Kgm2Label                       matlab.ui.control.Label
        Kgm2Label_2                     matlab.ui.control.Label
        Kgm2Label_3                     matlab.ui.control.Label
        KgLabel                         matlab.ui.control.Label
        cmLabel_3                       matlab.ui.control.Label
        VEditField                      matlab.ui.control.NumericEditField
        VEditField_2                    matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        xLabel_2                        matlab.ui.control.Label
        Kgm2Label_4                     matlab.ui.control.Label
        Kgm2Label_5                     matlab.ui.control.Label
        Kgm2Label_6                     matlab.ui.control.Label
        EditField_2Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        d0dtEditFieldLabel              matlab.ui.control.Label
        d0dtEditField                   matlab.ui.control.NumericEditField
        d0dtEditField_2Label            matlab.ui.control.Label
        d0dtEditField_2                 matlab.ui.control.NumericEditField
        d0dtEditField_3Label            matlab.ui.control.Label
        d0dtEditField_3                 matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        VEditField_3Label               matlab.ui.control.Label
        VEditField_3                    matlab.ui.control.NumericEditField
        SxEditFieldLabel                matlab.ui.control.Label
        SxEditField                     matlab.ui.control.NumericEditField
        IxxEditFieldLabel               matlab.ui.control.Label
        IxxEditField                    matlab.ui.control.NumericEditField
        IyyEditFieldLabel               matlab.ui.control.Label
        IyyEditField                    matlab.ui.control.NumericEditField
        IzzEditFieldLabel               matlab.ui.control.Label
        IzzEditField                    matlab.ui.control.NumericEditField
        SyEditFieldLabel                matlab.ui.control.Label
        SyEditField                     matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        SzEditFieldLabel                matlab.ui.control.Label
        SzEditField                     matlab.ui.control.NumericEditField
        IxzEditFieldLabel               matlab.ui.control.Label
        IxzEditField                    matlab.ui.control.NumericEditField
        IxyEditFieldLabel               matlab.ui.control.Label
        IxyEditField                    matlab.ui.control.NumericEditField
        IyzEditFieldLabel               matlab.ui.control.Label
        IyzEditField                    matlab.ui.control.NumericEditField
        LibraryTab                      matlab.ui.container.Tab
        AddSensorButton                 matlab.ui.control.Button
        AddActuatorButton               matlab.ui.control.Button
        AvailableSensorsLabel           matlab.ui.control.Label
        AvailableSensorsDropDown        matlab.ui.control.DropDown
        AvailableActuatorsDropDownLabel  matlab.ui.control.Label
        AvailableActuatorsDropDown      matlab.ui.control.DropDown
        Tree                            matlab.ui.container.Tree
        SensorsNode                     matlab.ui.container.TreeNode
        ActuatorsNode                   matlab.ui.container.TreeNode
        EditButton                      matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        AlgorithmsTab                   matlab.ui.container.Tab
        AvailableDeterminationAlgorithmsLabel  matlab.ui.control.Label
        AvailableControlAlgorithmsLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        AddDeterminationAlgorithmButton  matlab.ui.control.Button
        AddControlAlgorithmButton       matlab.ui.control.Button
        ViewDeterminationAlgorithmButton  matlab.ui.control.Button
        ViewDeterminationAlgorithmButton_2  matlab.ui.control.Button
        ViewTab                         matlab.ui.container.Tab
        MeasuredBerrorCheckBox          matlab.ui.control.CheckBox
        SENSORERRORSLabel               matlab.ui.control.Label
        MeasuredSunvectorerrorCheckBox  matlab.ui.control.CheckBox
        MeasuredSunAngleerrorCheckBox   matlab.ui.control.CheckBox
        MeasuredRateserrorCheckBox      matlab.ui.control.CheckBox
        OBCERRORSLabel                  matlab.ui.control.Label
        ComputedPositionErrorCheckBox   matlab.ui.control.CheckBox
        ComputedVelocityErrorCheckBox   matlab.ui.control.CheckBox
        ComputedSunVectorErrorCheckBox  matlab.ui.control.CheckBox
        ComputedMagneticFieldErrorCheckBox  matlab.ui.control.CheckBox
        ComputedTotalTorqueErrorCheckBox  matlab.ui.control.CheckBox
        POINTINGERRORLabel              matlab.ui.control.Label
        PointingErrorYPRCheckBox        matlab.ui.control.CheckBox
        W_PointingErrorCheckBox         matlab.ui.control.CheckBox
        DETERMINATIONERRORLabel         matlab.ui.control.Label
        est_errCheckBox                 matlab.ui.control.CheckBox
        RatesestimationerrorCheckBox    matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        % Counters
        n = 1;
        n_2 = 1; % Description
        n_F_SS = 1;
        n_Magnetometer = 1;
        n_Gyro = 1;
        aux = [0,0,0,0];
        aux2 = [0,0];
        
        % Strings
        C_SS_str;
        F_SS_str;
        Magnetometer_str;
        Gyro_str;
        
        Magnetorquer_str;
        Momentum_Wheel_str;
        
        % Simulation Parameters
        stop_time;
        solver;
        Sim
        
        %Plots
        b_error
        meas_sun_vec
        meas_sun_ang
        pos_error
        vel_error
        comp_sun_vec
        comp_mag_error
        comp_tot_torque_error
        pointing_error
        w_error
        meas_rate_error
        est_error
        Rates_estimation_error
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %------------------------------------------------------
            %--------------------- MUDAR ISTO ---------------------
            %------------------------------------------------------
            %evalin('base','clear')
            %clc
            addpath('D:\Universidade\Engenharia Aeroespacial\5º Ano\1º Semestre\Projecto Aeroespacial\Projeto\ADCS_simulink_gui')                           
            
            app.stop_time = app.StopTimesecondsEditField.Value;
            app.solver = app.SolverDropDown.Value;
        end

        % Button pushed function: AddSensorButton
        function AddSensorButtonPushed(app, event)
            % Logic for sensor Three
            
            switch app.AvailableSensorsDropDown.Value
                case 'Coarse Sun Sensor'
                    
                    str = app.SensorsNode.Children;
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:6);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Coarse')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.C_SS_str = ['Coarse Sun Sersor ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.C_SS_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
                    
                case 'Fine Sun Sensor'                
                    
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:4);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Fine')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.F_SS_str = ['Fine Sun Sersor ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.F_SS_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
                    
                case 'Magnetometer'
                             
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:9);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Magnetome')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.Magnetometer_str = ['Magnetometer ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.Magnetometer_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);                  
                    
                case 'Gyroscope'
                    
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:9);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Gyroscope')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.Gyro_str = ['Gyroscope ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.Gyro_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
                    
            end
        end

        % Value changed function: AvailableSensorsDropDown
        function AvailableSensorsDropDownValueChanged(app, event)
            % Pedro - Working on it
            %value_sensor_select = app.AvailableSensorsDropDown.Value;            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % Logic to remove sensors from tree
            
        if isprop(app.Tree.SelectedNodes, 'Text')
            if strcmp(app.Tree.SelectedNodes.Text,'Sensors:') ~= 1 && strcmp(app.Tree.SelectedNodes.Text,'Actuators:') ~= 1             
                % If sensor clear flags
                if strcmp(app.Tree.SelectedNodes.Text(1:6),'Coarse') == 1
                flag = str2double(app.Tree.SelectedNodes.Text(19:end));
                app.aux(1) = app.aux(1) - 10^(flag-1);
                elseif strcmp(app.Tree.SelectedNodes.Text(1:4),'Fine') == 1
                flag = str2double(app.Tree.SelectedNodes.Text(17:end));
                app.aux(2) = app.aux(2) - 10^(flag-1);
                elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Magnetome') == 1
                flag = str2double(app.Tree.SelectedNodes.Text(14:end));
                app.aux(3) = app.aux(3) - 10^(flag-1);
                elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Gyroscope') == 1
                flag = str2double(app.Tree.SelectedNodes.Text(11:end));
                app.aux(4) = app.aux(4) - 10^(flag-1);
                
                % If actuator clear flags
                elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Magnetorq') == 1
                flag = str2double(app.Tree.SelectedNodes.Text(14:end));
                app.aux2(1) = app.aux2(1) - 10^(flag-1);
                elseif strcmp(app.Tree.SelectedNodes.Text(1:4),'Mome') == 1
                flag = str2double(app.Tree.SelectedNodes.Text(16:end));
                app.aux2(2) = app.aux2(2) - 10^(flag-1);
                end
                
                % Delete Node
                arrayfun(@delete,app.Tree.SelectedNodes)
            
            elseif strcmp(app.Tree.SelectedNodes.Text,'Sensors:') == 1
                % Clear all flags
                app.aux = [0,0,0,0];
                
                % Delete Three
                arrayfun(@delete,app.SensorsNode.Children)
                
            elseif strcmp(app.Tree.SelectedNodes.Text,'Actuators:') == 1
                % Clear all flags
                app.aux2 = [0,0];
                
                % Delete Three
                arrayfun(@delete,app.ActuatorsNode.Children)
            end     
        
       else 
            % Do nothing
       end                     
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            % Logic to edit sensors and actuators from tree
            
        if isprop(app.Tree.SelectedNodes, 'Text')
            
            % Logic to edit sensors from tree
            
            if strcmp(app.Tree.SelectedNodes.Text(1:6),'Coarse') == 1
                C_SS_gui(app.Tree.SelectedNodes.Text(19:end),app.aux(1))     %(number,flag)
                
                %Flag to control if sensor has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(19:end));
                app.aux(1) = rem(app.aux(1),10^(flag-1)) + floor(app.aux(1)/(10^(flag)))*10^(flag) + 10^(flag-1);
                
            elseif strcmp(app.Tree.SelectedNodes.Text(1:4),'Fine') == 1
                F_SS_gui(app.Tree.SelectedNodes.Text(17:end),app.aux(2))      %(number,flag)          
                
                %Flag to control if sensor has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(17:end));
                app.aux(2) = rem(app.aux(2),10^(flag-1)) + floor(app.aux(2)/(10^(flag)))*10^(flag) + 10^(flag-1);
                
            elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Magnetome') == 1    
                Magnetometer_gui(app.Tree.SelectedNodes.Text(14:end),app.aux(3))   %(number,flag)
                
                %Flag to control if sensor has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(14:end));
                app.aux(3) = rem(app.aux(3),10^(flag-1)) + floor(app.aux(3)/(10^(flag)))*10^(flag) + 10^(flag-1);
                
            elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Gyroscope') == 1
                Gyro_gui(app.Tree.SelectedNodes.Text(11:end),app.aux(4))   %(number,flag)
                
                %Flag to control if sensor has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(11:end));
                app.aux(4) = rem(app.aux(4),10^(flag-1)) + floor(app.aux(4)/(10^(flag)))*10^(flag) + 10^(flag-1);                
                
            % Logic to edit Actuators from tree
            
            elseif strcmp(app.Tree.SelectedNodes.Text(1:9),'Magnetorq') == 1
                Magnetorquer_gui(app.Tree.SelectedNodes.Text(14:end),app.aux2(1))     %(number,flag)
                
                %Flag to control if actuator has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(14:end));
                app.aux2(1) = rem(app.aux2(1),10^(flag-1)) + floor(app.aux2(1)/(10^(flag)))*10^(flag) + 10^(flag-1);
                
            elseif strcmp(app.Tree.SelectedNodes.Text(1:4),'Mome') == 1
                ReactionWheel_gui(app.Tree.SelectedNodes.Text(16:end),app.aux2(2))     %(number,flag)          
                
                %Flag to control if actuator has been edited or not
                flag = str2double(app.Tree.SelectedNodes.Text(16:end));
                app.aux2(2) = rem(app.aux2(2),10^(flag-1)) + floor(app.aux2(2)/(10^(flag)))*10^(flag) + 10^(flag-1);             
            end
        else
            % Do nothing
        end
        end

        % Button pushed function: RunSimulinkButton
        function RunSimulinkButtonPushed(app, event)
            % ------------Run Simulation-----------------
            
            evalin('base', 'Controller_gains_definition')
            
            app.EditField.Value = 'Processing...';
            drawnow() 
            
            % ATENCAO AO NOME DO FICHEIRO SIMULINK TEM DE SER IGUAL A ESTE
            app.Sim = sim('Pitch_Sun_Track','solver',app.solver,'TimeOut',app.stop_time);
            drawnow() 
            % -------------------------------------------
            
            app.EditField.Value = 'Complete!';
            
            % Plot checked plots
            if app.b_error
                Plot_1_var(app.Sim.ScopeData1,"Measured B error")
            end    
            if app.meas_sun_vec
                Plot_3_var(app.Sim.ScopeData2,"Measured Sun Vector error","Yaw","Pitch","Roll")
            end    
            if app.meas_sun_ang
                Plot_3_var(app.Sim.ScopeData3,"Measured Rates error","Yaw","Pitch","Roll")
            end    
            if app.meas_rate_error
                Plot_3_var(app.Sim.ScopeData4,"Measured Rate error","Yaw","Pitch","Roll")
            end      
            if app.pos_error
                Plot_3_var(app.Sim.ScopeData5,"Computed Position Error","Yaw","Pitch","Roll")
            end    
            if app.vel_error
                Plot_3_var(app.Sim.ScopeData6,"Computed Velocity Error","Yaw","Pitch","Roll")
            end                    
            if app.comp_sun_vec
                Plot_3_var(app.Sim.ScopeData7,"Computed Sun Vector Error","Yaw","Pitch","Roll")
            end    
            if app.comp_mag_error
                Plot_3_var(app.Sim.ScopeData8,"Computed Magnetic Field Error","Yaw","Pitch","Roll")
            end    
            if app.comp_tot_torque_error
                Plot_3_var(app.Sim.ScopeData9,"Computed Total Torque Error","Yaw","Pitch","Roll")
            end    
            if app.pointing_error
                Plot_3_var(app.Sim.ScopeData10,"Pointing Error YPR","Yaw","Pitch","Roll")
            end    
            if app.w_error
                Plot_3_var(app.Sim.ScopeData11,"W Pointing Error","Yaw","Pitch","Roll")
            end    
            if app.Rates_estimation_error
                Plot_3_var(app.Sim.ScopeData12,"Rates_estimation_error","M_x","M_y","M_z")
            end
            if app.est_error
                Plot_1_var(app.Sim.est_err,"Est Error")
            end
        end

        % Value changed function: StopTimesecondsEditField
        function StopTimesecondsEditFieldValueChanged(app, event)
            % Stop time
            app.stop_time = app.StopTimesecondsEditField.Value;  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Do when close GUI
            delete(app)
        end

        % Value changed function: MeasuredBerrorCheckBox
        function MeasuredBerrorCheckBoxValueChanged(app, event)
            % B Error Check Box
            app.b_error = app.MeasuredBerrorCheckBox.Value;%           
        end

        % Value changed function: MeasuredSunvectorerrorCheckBox
        function MeasuredSunvectorerrorCheckBoxValueChanged(app, event)
            % Measured Sun Vector Check Box
            app.meas_sun_vec = app.MeasuredSunvectorerrorCheckBox.Value;%            
        end

        % Value changed function: MeasuredSunAngleerrorCheckBox
        function MeasuredSunAngleerrorCheckBoxValueChanged(app, event)
            % Measured Sun angle Check Box
            app.meas_sun_ang = app.MeasuredSunAngleerrorCheckBox.Value;%           
        end

        % Value changed function: ComputedPositionErrorCheckBox
        function ComputedPositionErrorCheckBoxValueChanged(app, event)
            % Measured Position Error Check Box
            app.pos_error = app.ComputedPositionErrorCheckBox.Value;%            
        end

        % Value changed function: ComputedVelocityErrorCheckBox
        function ComputedVelocityErrorCheckBoxValueChanged(app, event)
            % Measured Velocity Error Check Box
            app.vel_error = app.ComputedVelocityErrorCheckBox.Value;%            
        end

        % Value changed function: ComputedSunVectorErrorCheckBox
        function ComputedSunVectorErrorCheckBoxValueChanged(app, event)
            % Computed Sun Vector Check Box
            app.comp_sun_vec = app.ComputedSunVectorErrorCheckBox.Value;%           
        end

        % Value changed function: ComputedMagneticFieldErrorCheckBox
        function ComputedMagneticFieldErrorCheckBoxValueChanged(app, event)
            % Computed Magnetic Field Error Check Box
            app.comp_mag_error = app.ComputedMagneticFieldErrorCheckBox.Value;%            
        end

        % Value changed function: ComputedTotalTorqueErrorCheckBox
        function ComputedTotalTorqueErrorCheckBoxValueChanged(app, event)
            % Computed Total Torque Error Check Box
            app.comp_tot_torque_error = app.ComputedTotalTorqueErrorCheckBox.Value;%            
        end

        % Value changed function: PointingErrorYPRCheckBox
        function PointingErrorYPRCheckBoxValueChanged(app, event)
            % Pointing Error Check Box
            app.pointing_error = app.PointingErrorYPRCheckBox.Value;%           
        end

        % Value changed function: W_PointingErrorCheckBox
        function W_PointingErrorCheckBoxValueChanged(app, event)
            % W Error Check Box
            app.w_error = app.W_PointingErrorCheckBox.Value;%            
        end

        % Value changed function: MeasuredRateserrorCheckBox
        function MeasuredRateserrorCheckBoxValueChanged(app, event)
            % Measured Rate Error Check Box
            app.meas_rate_error = app.MeasuredRateserrorCheckBox.Value;%           
        end

        % Value changed function: est_errCheckBox
        function est_errCheckBoxValueChanged(app, event)
            % Estimated Error Check Box
            app.est_error = app.est_errCheckBox.Value;%          
        end

        % Button pushed function: AddActuatorButton
        function AddActuatorButtonPushed(app, event)
            % Logic for actuator Three
            
            switch app.AvailableActuatorsDropDown.Value
                case 'Magnetorquer'
                    
                    str = app.ActuatorsNode.Children;
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:9);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Magnetorq')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.Magnetorquer_str = ['Magnetorquer ', num2str(app.n_2)];
                    
                    uitreenode(app.ActuatorsNode,'Text',app.Magnetorquer_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
               
                case 'Momentum Wheel'
                    
                    str = app.ActuatorsNode.Children;
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:4);
                        str_num = char(regexp(str(app.n).Text,'\d*','Match'));
                        if strcmp(str_text,'Mome')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.Momentum_Wheel_str = ['Momentum Wheel ', num2str(app.n_2)];
                    
                    uitreenode(app.ActuatorsNode,'Text',app.Momentum_Wheel_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);     
            end
        end

        % Value changed function: RatesestimationerrorCheckBox
        function RatesestimationerrorCheckBoxValueChanged(app, event)
           app.Rates_estimation_error = app.RatesestimationerrorCheckBox.Value;   
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            app.solver = app.SolverDropDown.Value;
        end

        % Callback function
        function PerturbationsButtonPushed2(app, event)
            % Button Perturbations
            EnvironmentandPerturbations
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainSystemTab = uitab(app.TabGroup);
            app.MainSystemTab.Title = 'Main System';
            app.RunSimulinkButton = uibutton(app.MainSystemTab, 'push');
            app.RunSimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulinkButtonPushed, true);
            app.RunSimulinkButton.Text = 'Run Simulink';
            app.StopTimesecondsEditFieldLabel = uilabel(app.MainSystemTab);
            app.StopTimesecondsEditFieldLabel.Text = 'Stop Time (seconds)';
            app.StopTimesecondsEditField = uieditfield(app.MainSystemTab, 'numeric');
            app.StopTimesecondsEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimesecondsEditFieldValueChanged, true);
            app.StopTimesecondsEditField.Value = 200;
            app.SolverDropDownLabel = uilabel(app.MainSystemTab);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.MainSystemTab);
            app.SolverDropDown.Items = {'ode8', 'ode5', 'Option 3', 'Option 4'};
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Value = 'ode8';
            app.ADCSSimulatorforCubeSatLabel = uilabel(app.MainSystemTab);
            app.ADCSSimulatorforCubeSatLabel.Text = 'ADCS Simulator for CubeSat';
            app.StepSizesecondsEditFieldLabel = uilabel(app.MainSystemTab);
            app.StepSizesecondsEditFieldLabel.Text = 'Step Size (seconds)';
            app.StepSizesecondsEditField = uieditfield(app.MainSystemTab, 'numeric');
            app.StepSizesecondsEditField.Value = 0.1;
            app.EditField = uieditfield(app.MainSystemTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.OrbitandEnvironmentTab = uitab(app.TabGroup);
            app.OrbitandEnvironmentTab.Title = 'Orbit and Environment';
            app.PerturbationsButton = uibutton(app.OrbitandEnvironmentTab, 'push');
            app.PerturbationsButton.Text = 'Perturbations';
            app.SpacecraftParametersLabel = uilabel(app.OrbitandEnvironmentTab);
            app.SpacecraftParametersLabel.Text = 'Spacecraft Parameters';
            app.StartEpochEditFieldLabel = uilabel(app.OrbitandEnvironmentTab);
            app.StartEpochEditFieldLabel.Text = 'Start Epoch';
            app.StartEpochEditField = uieditfield(app.OrbitandEnvironmentTab, 'numeric');
            app.EpochFormatDropDownLabel = uilabel(app.OrbitandEnvironmentTab);
            app.EpochFormatDropDownLabel.Text = 'Epoch Format';
            app.EpochFormatDropDown = uidropdown(app.OrbitandEnvironmentTab);
            app.EpochFormatDropDown.Items = {'A1ModJulian', 'UTCGregorian', 'UTCModJulian', 'TDBModJulian'};
            app.EpochFormatDropDown.Value = 'A1ModJulian';
            app.MeanAnomalydegEditFieldLabel = uilabel(app.OrbitandEnvironmentTab);
            app.MeanAnomalydegEditFieldLabel.Text = 'Mean Anomaly (deg)';
            app.MeanAnomalydegEditField = uieditfield(app.OrbitandEnvironmentTab, 'numeric');
            app.InclinationdegEditFieldLabel = uilabel(app.OrbitandEnvironmentTab);
            app.InclinationdegEditFieldLabel.Text = 'Inclination (deg)';
            app.InclinationdegEditField = uieditfield(app.OrbitandEnvironmentTab, 'numeric');
            app.ArgumentofPerigeedegEditFieldLabel = uilabel(app.OrbitandEnvironmentTab);
            app.ArgumentofPerigeedegEditFieldLabel.Text = 'Argument of Perigee (deg)';
            app.ArgumentofPerigeedegEditField = uieditfield(app.OrbitandEnvironmentTab, 'numeric');
            app.LongitudeofAscendingNodedegEditFieldLabel = uilabel(app.OrbitandEnvironmentTab);
            app.LongitudeofAscendingNodedegEditFieldLabel.Text = 'Longitude of Ascending Node (deg)';
            app.LongitudeofAscendingNodedegEditField = uieditfield(app.OrbitandEnvironmentTab, 'numeric');
            app.SemiMajorAxiskmEditFieldLabel = uilabel(app.OrbitandEnvironmentTab);
            app.SemiMajorAxiskmEditFieldLabel.Text = 'Semi-Major Axis (km)';
            app.SemiMajorAxiskmEditField = uieditfield(app.OrbitandEnvironmentTab, 'numeric');
            app.EccentricityEditFieldLabel = uilabel(app.OrbitandEnvironmentTab);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.OrbitandEnvironmentTab, 'numeric');
            app.CoordinateSystemDropDownLabel = uilabel(app.OrbitandEnvironmentTab);
            app.CoordinateSystemDropDownLabel.Text = 'Coordinate System';
            app.CoordinateSystemDropDown = uidropdown(app.OrbitandEnvironmentTab);
            app.SpacecraftTab = uitab(app.TabGroup);
            app.SpacecraftTab.Title = 'Spacecraft';
            app.radLabel = uilabel(app.SpacecraftTab);
            app.radLabel.Text = 'rad';
            app.ReferenceFrameDropDown = uidropdown(app.SpacecraftTab);
            app.ReferenceFrameDropDown.Items = {'Body reference frame', 'Orbit reference frame', 'ECI frame', 'Option 4'};
            app.ReferenceFrameDropDown.Value = 'Body reference frame';
            app.radLabel_2 = uilabel(app.SpacecraftTab);
            app.radLabel_2.Text = 'rad';
            app.radLabel_3 = uilabel(app.SpacecraftTab);
            app.radLabel_3.Text = 'rad';
            app.ReferenceFrameDropDownLabel = uilabel(app.SpacecraftTab);
            app.ReferenceFrameDropDownLabel.Text = 'Reference Frame';
            app.cm3Label = uilabel(app.SpacecraftTab);
            app.cm3Label.Text = 'cm3';
            app.cmLabel = uilabel(app.SpacecraftTab);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.SpacecraftTab);
            app.cmLabel_2.Text = 'cm';
            app.Kgm2Label = uilabel(app.SpacecraftTab);
            app.Kgm2Label.Text = 'Kgm2';
            app.Kgm2Label_2 = uilabel(app.SpacecraftTab);
            app.Kgm2Label_2.Text = 'Kgm2';
            app.Kgm2Label_3 = uilabel(app.SpacecraftTab);
            app.Kgm2Label_3.Text = 'Kgm2';
            app.KgLabel = uilabel(app.SpacecraftTab);
            app.KgLabel.Text = 'Kg';
            app.cmLabel_3 = uilabel(app.SpacecraftTab);
            app.cmLabel_3.Text = 'cm';
            app.VEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.VEditField_2 = uieditfield(app.SpacecraftTab, 'numeric');
            app.xLabel = uilabel(app.SpacecraftTab);
            app.xLabel.Text = 'x';
            app.xLabel_2 = uilabel(app.SpacecraftTab);
            app.xLabel_2.Text = 'x';
            app.Kgm2Label_4 = uilabel(app.SpacecraftTab);
            app.Kgm2Label_4.Text = 'Kgm2';
            app.Kgm2Label_5 = uilabel(app.SpacecraftTab);
            app.Kgm2Label_5.Text = 'Kgm2';
            app.Kgm2Label_6 = uilabel(app.SpacecraftTab);
            app.Kgm2Label_6.Text = 'Kgm2';
            app.EditField_2Label = uilabel(app.SpacecraftTab);
            app.EditField_2Label.Text = 'θ0';
            app.EditField_2 = uieditfield(app.SpacecraftTab, 'numeric');
            app.EditField_3Label = uilabel(app.SpacecraftTab);
            app.EditField_3Label.Text = 'β0';
            app.EditField_3 = uieditfield(app.SpacecraftTab, 'numeric');
            app.d0dtEditFieldLabel = uilabel(app.SpacecraftTab);
            app.d0dtEditFieldLabel.Text = 'dθ0/dt';
            app.d0dtEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.d0dtEditField_2Label = uilabel(app.SpacecraftTab);
            app.d0dtEditField_2Label.Text = 'dβ0/dt';
            app.d0dtEditField_2 = uieditfield(app.SpacecraftTab, 'numeric');
            app.d0dtEditField_3Label = uilabel(app.SpacecraftTab);
            app.d0dtEditField_3Label.Text = 'dγ0/dt';
            app.d0dtEditField_3 = uieditfield(app.SpacecraftTab, 'numeric');
            app.EditField_4Label = uilabel(app.SpacecraftTab);
            app.EditField_4Label.Text = 'γ0';
            app.EditField_4 = uieditfield(app.SpacecraftTab, 'numeric');
            app.VEditField_3Label = uilabel(app.SpacecraftTab);
            app.VEditField_3Label.Text = 'V';
            app.VEditField_3 = uieditfield(app.SpacecraftTab, 'numeric');
            app.SxEditFieldLabel = uilabel(app.SpacecraftTab);
            app.SxEditFieldLabel.Text = 'Sx';
            app.SxEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.IxxEditFieldLabel = uilabel(app.SpacecraftTab);
            app.IxxEditFieldLabel.Text = 'Ixx';
            app.IxxEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.IyyEditFieldLabel = uilabel(app.SpacecraftTab);
            app.IyyEditFieldLabel.Text = 'Iyy';
            app.IyyEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.IzzEditFieldLabel = uilabel(app.SpacecraftTab);
            app.IzzEditFieldLabel.Text = 'Izz';
            app.IzzEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.SyEditFieldLabel = uilabel(app.SpacecraftTab);
            app.SyEditFieldLabel.Text = 'Sy';
            app.SyEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.mEditFieldLabel = uilabel(app.SpacecraftTab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.SzEditFieldLabel = uilabel(app.SpacecraftTab);
            app.SzEditFieldLabel.Text = 'Sz';
            app.SzEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.IxzEditFieldLabel = uilabel(app.SpacecraftTab);
            app.IxzEditFieldLabel.Text = 'Ixz';
            app.IxzEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.IxyEditFieldLabel = uilabel(app.SpacecraftTab);
            app.IxyEditFieldLabel.Text = 'Ixy';
            app.IxyEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.IyzEditFieldLabel = uilabel(app.SpacecraftTab);
            app.IyzEditFieldLabel.Text = 'Iyz';
            app.IyzEditField = uieditfield(app.SpacecraftTab, 'numeric');
            app.LibraryTab = uitab(app.TabGroup);
            app.LibraryTab.Title = 'Library';
            app.AddSensorButton = uibutton(app.LibraryTab, 'push');
            app.AddSensorButton.ButtonPushedFcn = createCallbackFcn(app, @AddSensorButtonPushed, true);
            app.AddSensorButton.Text = 'Add Sensor';
            app.AddActuatorButton = uibutton(app.LibraryTab, 'push');
            app.AddActuatorButton.ButtonPushedFcn = createCallbackFcn(app, @AddActuatorButtonPushed, true);
            app.AddActuatorButton.Text = 'Add Actuator';
            app.AvailableSensorsLabel = uilabel(app.LibraryTab);
            app.AvailableSensorsLabel.Text = 'Available Sensors:';
            app.AvailableSensorsDropDown = uidropdown(app.LibraryTab);
            app.AvailableSensorsDropDown.Items = {'Coarse Sun Sensor', 'Fine Sun Sensor', 'Gyroscope', 'Magnetometer'};
            app.AvailableSensorsDropDown.ValueChangedFcn = createCallbackFcn(app, @AvailableSensorsDropDownValueChanged, true);
            app.AvailableSensorsDropDown.Value = 'Coarse Sun Sensor';
            app.AvailableActuatorsDropDownLabel = uilabel(app.LibraryTab);
            app.AvailableActuatorsDropDownLabel.Text = 'Available Actuators:';
            app.AvailableActuatorsDropDown = uidropdown(app.LibraryTab);
            app.AvailableActuatorsDropDown.Items = {'Magnetorquer', 'Momentum Wheel'};
            app.AvailableActuatorsDropDown.Value = 'Magnetorquer';
            app.Tree = uitree(app.LibraryTab);
            app.SensorsNode = uitreenode(app.Tree);
            app.SensorsNode.Tag = 'Sensors';
            app.SensorsNode.Text = 'Sensors:';
            app.ActuatorsNode = uitreenode(app.Tree);
            app.ActuatorsNode.Text = 'Actuators:';
            app.EditButton = uibutton(app.LibraryTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.RemoveButton = uibutton(app.LibraryTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.AlgorithmsTab = uitab(app.TabGroup);
            app.AlgorithmsTab.Title = 'Algorithms';
            app.AvailableDeterminationAlgorithmsLabel = uilabel(app.AlgorithmsTab);
            app.AvailableDeterminationAlgorithmsLabel.Text = 'Available Determination Algorithms:';
            app.AvailableControlAlgorithmsLabel = uilabel(app.AlgorithmsTab);
            app.AvailableControlAlgorithmsLabel.Text = 'Available Control Algorithms:';
            app.DropDown = uidropdown(app.AlgorithmsTab);
            app.DropDown.Items = {'Bang_Bang_2', 'Bang_Bang_1', 'PDI', 'PD'};
            app.DropDown.Value = 'Bang_Bang_2';
            app.DropDown_2 = uidropdown(app.AlgorithmsTab);
            app.DropDown_2.Items = {'EKF_1', 'Kalman_Filter', 'MFK', 'EKF_2'};
            app.DropDown_2.Value = 'EKF_1';
            app.AddDeterminationAlgorithmButton = uibutton(app.AlgorithmsTab, 'push');
            app.AddDeterminationAlgorithmButton.Text = 'Add Determination Algorithm';
            app.AddControlAlgorithmButton = uibutton(app.AlgorithmsTab, 'push');
            app.AddControlAlgorithmButton.Text = 'Add Control Algorithm';
            app.ViewDeterminationAlgorithmButton = uibutton(app.AlgorithmsTab, 'push');
            app.ViewDeterminationAlgorithmButton.Text = 'View Determination Algorithm';
            app.ViewDeterminationAlgorithmButton_2 = uibutton(app.AlgorithmsTab, 'push');
            app.ViewDeterminationAlgorithmButton_2.Text = 'View Determination Algorithm';
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.MeasuredBerrorCheckBox = uicheckbox(app.ViewTab);
            app.MeasuredBerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasuredBerrorCheckBoxValueChanged, true);
            app.MeasuredBerrorCheckBox.Text = 'Measured B error';
            app.SENSORERRORSLabel = uilabel(app.ViewTab);
            app.SENSORERRORSLabel.Text = 'SENSOR ERRORS';
            app.MeasuredSunvectorerrorCheckBox = uicheckbox(app.ViewTab);
            app.MeasuredSunvectorerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasuredSunvectorerrorCheckBoxValueChanged, true);
            app.MeasuredSunvectorerrorCheckBox.Text = 'Measured Sun vector error';
            app.MeasuredSunAngleerrorCheckBox = uicheckbox(app.ViewTab);
            app.MeasuredSunAngleerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasuredSunAngleerrorCheckBoxValueChanged, true);
            app.MeasuredSunAngleerrorCheckBox.Text = 'Measured Sun Angle error';
            app.MeasuredRateserrorCheckBox = uicheckbox(app.ViewTab);
            app.MeasuredRateserrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasuredRateserrorCheckBoxValueChanged, true);
            app.MeasuredRateserrorCheckBox.Text = 'Measured Rates error';
            app.OBCERRORSLabel = uilabel(app.ViewTab);
            app.OBCERRORSLabel.Text = 'OBC ERRORS';
            app.ComputedPositionErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedPositionErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedPositionErrorCheckBoxValueChanged, true);
            app.ComputedPositionErrorCheckBox.Text = 'Computed Position Error';
            app.ComputedVelocityErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedVelocityErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedVelocityErrorCheckBoxValueChanged, true);
            app.ComputedVelocityErrorCheckBox.Text = 'Computed Velocity Error';
            app.ComputedSunVectorErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedSunVectorErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedSunVectorErrorCheckBoxValueChanged, true);
            app.ComputedSunVectorErrorCheckBox.Text = 'Computed Sun Vector Error';
            app.ComputedMagneticFieldErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedMagneticFieldErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedMagneticFieldErrorCheckBoxValueChanged, true);
            app.ComputedMagneticFieldErrorCheckBox.Text = 'Computed Magnetic Field Error';
            app.ComputedTotalTorqueErrorCheckBox = uicheckbox(app.ViewTab);
            app.ComputedTotalTorqueErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputedTotalTorqueErrorCheckBoxValueChanged, true);
            app.ComputedTotalTorqueErrorCheckBox.Text = 'Computed Total Torque Error';
            app.POINTINGERRORLabel = uilabel(app.ViewTab);
            app.POINTINGERRORLabel.Text = 'POINTING ERROR';
            app.PointingErrorYPRCheckBox = uicheckbox(app.ViewTab);
            app.PointingErrorYPRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PointingErrorYPRCheckBoxValueChanged, true);
            app.PointingErrorYPRCheckBox.Text = 'Pointing Error YPR';
            app.W_PointingErrorCheckBox = uicheckbox(app.ViewTab);
            app.W_PointingErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @W_PointingErrorCheckBoxValueChanged, true);
            app.W_PointingErrorCheckBox.Text = 'W_Pointing Error';
            app.DETERMINATIONERRORLabel = uilabel(app.ViewTab);
            app.DETERMINATIONERRORLabel.Text = 'DETERMINATION ERROR';
            app.est_errCheckBox = uicheckbox(app.ViewTab);
            app.est_errCheckBox.ValueChangedFcn = createCallbackFcn(app, @est_errCheckBoxValueChanged, true);
            app.est_errCheckBox.Text = 'est_err';
            app.RatesestimationerrorCheckBox = uicheckbox(app.ViewTab);
            app.RatesestimationerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @RatesestimationerrorCheckBoxValueChanged, true);
            app.RatesestimationerrorCheckBox.Text = 'Rates estimation error';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end